classdef maskAndAnalyze_workDev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MaskingcontrastandbinarizationTab  matlab.ui.container.Tab
        BackgroundsubtractionPanel      matlab.ui.container.Panel
        UselogYaxisforhistogramsCheckBox  matlab.ui.control.CheckBox
        ShowbackgroundsubtractedcontraandhistogramButton  matlab.ui.control.Button
        ShowbackgroundsubtractedipsiandhistogramButton  matlab.ui.control.Button
        ShowrawcontraandhistogramButton  matlab.ui.control.Button
        ShowrawipsiandhistogramButton   matlab.ui.control.Button
        Entercontrathresholdtouse1255EditField  matlab.ui.control.NumericEditField
        Entercontrathresholdtouse1255EditFieldLabel  matlab.ui.control.Label
        IpsibackgroundstatsLabel        matlab.ui.control.Label
        BackgroundROIsetLamp            matlab.ui.control.Lamp
        BackgroundROIsetLampLabel       matlab.ui.control.Label
        Enteripsithresholdtouse1255EditField  matlab.ui.control.NumericEditField
        Enteripsithresholdtouse1255EditFieldLabel  matlab.ui.control.Label
        backgroundStatsTable            matlab.ui.control.Table
        DrawROIforbackgroundButton      matlab.ui.control.Button
        contraSetLamp                   matlab.ui.control.Lamp
        ipsiSetLamp                     matlab.ui.control.Lamp
        contraTextName                  matlab.ui.control.EditField
        ipsiTextName                    matlab.ui.control.EditField
        SelectipsiimageButton           matlab.ui.control.Button
        SelectcontraimageButton         matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        SavewhatisshowntoanimageButton_MaskTab  matlab.ui.control.Button
        SaveasmatButton                 matlab.ui.control.Button
        MaskingPanel                    matlab.ui.container.Panel
        RefinemaskButton                matlab.ui.control.Button
        FinalROIgeneratedLamp           matlab.ui.control.Lamp
        FinalROIgeneratedLampLabel      matlab.ui.control.Label
        ContouriterationsEditField      matlab.ui.control.NumericEditField
        ContouriterationsEditFieldLabel  matlab.ui.control.Label
        SmoothfactorSlider              matlab.ui.control.Slider
        SmoothfactorSliderLabel         matlab.ui.control.Label
        ContractionbiasSlider           matlab.ui.control.Slider
        ContractionbiasSliderLabel      matlab.ui.control.Label
        MasksetLamp                     matlab.ui.control.Lamp
        MasksetLampLabel                matlab.ui.control.Label
        DrawmaskButton                  matlab.ui.control.Button
        AnalyzesinglematTab             matlab.ui.container.Tab
        SavewhatisshowntoanimageButton_AnalyzeTab  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        FigGenTabs                      matlab.ui.container.TabGroup
        ImagegenerationTab              matlab.ui.container.Tab
        ShowrawcompositewithROIandbackgroundoutlinedButton  matlab.ui.control.Button
        ShowbackgroundsubtractedcompositeButton  matlab.ui.control.Button
        ShowrawcompositeButton          matlab.ui.control.Button
        ShowbackgroundsubtractedcontraButton  matlab.ui.control.Button
        ShowbackgroundsubtractedipsiButton  matlab.ui.control.Button
        ShowrawcontraButton             matlab.ui.control.Button
        ShowrawipsiButton               matlab.ui.control.Button
        Showtiledoverlaptype2Button     matlab.ui.control.Button
        Showtiledoverlaptype1Button     matlab.ui.control.Button
        PlottingTab                     matlab.ui.container.Tab
        SaveRdistribtionplotdatatoexcelButton  matlab.ui.control.Button
        NumberofchangedpixelsEditField  matlab.ui.control.NumericEditField
        NumberofchangedpixelsEditFieldLabel  matlab.ui.control.Label
        SaveRdistributionpixeldatatoexcelButton  matlab.ui.control.Button
        UserawipsicontraCheckBox        matlab.ui.control.CheckBox
        UsebinaryoverlaponlyCheckBox    matlab.ui.control.CheckBox
        NumberofskippedpixelsEditField  matlab.ui.control.NumericEditField
        NumberofskippedpixelsEditFieldLabel  matlab.ui.control.Label
        BinsizelogunitsEditField        matlab.ui.control.NumericEditField
        BinsizelogunitsEditFieldLabel   matlab.ui.control.Label
        PixelsusedforcalculationEditField  matlab.ui.control.NumericEditField
        PixelsusedforcalculationEditFieldLabel  matlab.ui.control.Label
        RdistributionstandarddeviationEditField  matlab.ui.control.NumericEditField
        RdistributionstandarddeviationEditFieldLabel  matlab.ui.control.Label
        RdistributionvarianceEditField  matlab.ui.control.NumericEditField
        RdistributionvarianceEditFieldLabel  matlab.ui.control.Label
        ShowRdistributionButton         matlab.ui.control.Button
        SetdotcolorButton               matlab.ui.control.Button
        ScatterDotColor                 matlab.ui.control.Lamp
        DotsizeEditField                matlab.ui.control.NumericEditField
        DotsizeEditFieldLabel           matlab.ui.control.Label
        ScatteripsivscontraButton       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        IpsifilenameEditField           matlab.ui.control.EditField
        IpsifilenameEditFieldLabel      matlab.ui.control.Label
        SelectmatButton                 matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        defaultPath     % Default as matlab base path, but update every time user selects an input or output
        rawImgsPath     % Updated by select images button callback
        filesCells      % Updated by getFiles_F
        ipsiSelected    % Bool when ipsi image selected. This and contraSelected added 08-2023
        contraSelected
        backgroundMask  % Used by background subtraction. 08-2023 addition
        currIpsiName    % Updated by drowpdown callback. Char name of current ipsi file (no path)
        currContraName  % Updated by drowpdown callback. Char name of current contra file (no path)
        currShown          % Whatever is currently being shown in UIaxes. Updated by drawImgToAxes_F
        rawIpsiIm          % Image as read from file (no cropping/contrast adjustment). uint8 grayscale
        rawContraIm
        ROIHandle
        ROIMask
        ROIMask_Fin  % Refined version of ROIMask after refine mask button pressed (and only largest kept etc)
        contrastIpsiIm   % !! Note that as of august 2023, this contains the background subtracted data (same name for compatibility reasons)
        contrastContraIm % !! Note that as of august 2023, this contains the background subtracted data (same name for compatibility reasons)
        rDistIntensityType      % DEV TODO needed if not contrast adjusting? Either 'raw' or 'backgroundSubtracted'
        rDistPixSelected        % 'allInsideROI' or 'overlapOnly'
        binThreshIpsi    % As of 08-2023 is the threshold used in background subtraction (after subtraction when binarizing, values > than this are true. Otherwise false)
        binThreshContra
        binIpsiIm        % app.contrastIpsiIm after binarization
        binContraIm      % app.contrastContraIm after binarization
        binOverlapIm     % Logical AND of binIpsiIm and binContraIm

        an_Single        % an_ indiciates variables only used with Analyze single tab. m_Single is datastruct as loaded from .mat selected
        an_ipsiRemBin    % Binarized ipsi after overlap pixels removed
        an_contraRemBin  % Binarized contra after overlap pixels removed
        an_overlapBin    % The binary overlap image
        an_ROIPix        % # Pixels total in ROI
        an_ipsiROIPix    % # of ipsi pixels in binary image under ROI
        an_contraROIPix  % # of contra pixels in binary image under ROI
        an_overlapROIPix % # of overlap (ipsi/contra binary) pixels under ROI
        an_overlapArray  % [xCoord yCoord ipsiIntensity contraIntensity]. one row per overlap pixel. Intensity normalized to 0->1
        an_RLogArray     % log10 of ipsi/contra (col 3 divided by col 4 in m_overlapArray)
    end
    
    methods (Access = private)
        
        function numFiles = getFiles_F(app)
            app.filesCells = {};
            extFilt = '0'; % Any image (was .tif before)
            currDir = dir(app.rawImgsPath);
            %% Remove invalid files
            remVec = zeros(numel(currDir), 1);
            for v = 1:numel(currDir)
                if strcmp(currDir(v).name(1), '.') % ., .., ._ files, etc
                    remVec(v) = 1;
                    continue;
                end
                if strcmp(currDir(v).name, 'desktop.ini')
                    remVec(v) = 1;
                    continue;
                end
            end
            currDir(find(remVec)) = []; %#ok<FNDSB> 
            %% Put valid files into structure
            n = 0;
            indx = 0;
            if ~strcmp(extFilt, '0')
                for i = 1:numel(currDir) %#ok<UNRCH>
                    currFile = currDir(i).name;
                    if ~endsWith(currFile, extFilt, 'IgnoreCase', true)
                        n = n+1;
                        indx(n, 1) = i; %#ok<AGROW>
                    end
                end
                if ~(indx == 0); currDir(indx) = []; end
                if numel(currDir) == 0
                    disp('WARNING:: No files found with that extension');
                    numFiles = 0;
                    return
                end
            end
            numFiles = numel(currDir);
            for n = 1:numFiles
                app.filesCells{n,1} = strcat(currDir(n).folder, '\', currDir(n).name);
                app.filesCells{n,2} = currDir(n).name;
                
            end
        end
        
        function pS = parseImageName_F(~, imgString)
            %{
            Not used as of 08-2023 (moved to simple select ipsi/contra manually)

            For imgString see second column of getFiles_F output
            
            1st entry= MouseID = B106 (either a letter+ 3 numbers or 4 numbers)
            2nd entry= SlideNumber= 3 (always a number)
            3rd entry= RowNumber= 3 (always a number)
            4th entry= SliceNumber= 3 (always a number)�
            5th entry= Hemisphere= L (R or L)
            6th entry= SegregationType= ipsi (contra or ipsi)
            7th entry= Filter= CYP (I only use CYP or GFP but this could be different for future users)

            pS = parsed struct            
            %}
            splitElements = split(imgString, ' ');
            pS.MouseID = char(splitElements{1});
            pS.SlideNumber = str2double(splitElements{2});
            pS.RowNumber = str2double(splitElements{3});
            pS.SliceNumber = str2double(splitElements{4});
            pS.Hemisphere = char(splitElements{5});
            pS.SegregationType = char(splitElements{6});
            pS.Filter = char(splitElements{7});            
        end
        
        function drawImgToAxes_F(app, img)
            I = imshow(img, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
            app.currShown = img; % So can grab later with contour etc
        end
        
        function resetPanelsAndLamps_F(app, mode, offColor)
            %{
            January 2022
            David Alston
            Helper function to reset UI lamps
            Mode to switch which ones are being reset:
                'Mask' = Masking only
                'Back' = Background subtraction only

            offColor = [R G B] for lamps. Usually [1 0 1] (magenta)

            Note always disables the save as mat button regardless of mode
            %}
            switch mode
                case 'Mask' % Masking panel only
                    app.MasksetLamp.Color = offColor;
                    app.FinalROIgeneratedLamp.Color = offColor;
                case 'Back' % Background subtraction only
                    %app.BinarizePanel.Enable = 'off';
                    app.BackgroundROIsetLamp.Color = offColor;                    
                otherwise
                    beep;
                    uialert(app.UIFigure, 'Unhandled input to panel reset function. Check code/contact David Alston', 'resetPanelsAndLamps_F unhandled case');                  
            end
            app.SaveasmatButton.Enable = 'off';
        end
        
        function clearInternalImages_F(app)
            % Helper used to clear internal images/masks (done on dropdown change in masking mode)
            app.currShown = [];
            app.rawIpsiIm = [];
            app.rawContraIm = [];
            app.ROIMask = [];
            app.ROIMask_Fin = [];
            app.contrastIpsiIm = [];
            app.contrastContraIm = [];
            app.backgroundMask = [];
            app.binIpsiIm = [];
            app.binContraIm = [];
            app.binOverlapIm = [];
        end
        
        function analyzeMat_F(app)
            % Put into function to allow easy automatic recalculation when checkboxes change
            % (overlap or all pixels etc)
            %
            % As of 08-2023, contrastIpsiImg and contrastContraImg
            % are background subtracted images (kept old name for compatibility)
            % New as of 11-2023:
            % Pixels where both ipsi and contra are 0 are kept as 0 (and
            % later skipped. Pixels where only one are 0 are changed to 1
            % (to not skew R distribution)
            %% Use checkbox for using contrast adj vs original        
            if app.UserawipsicontraCheckBox.Value
                normIpsi = app.an_Single.rawIpsiImg;
                normContra = app.an_Single.rawContraImg;
                app.rDistIntensityType = 'raw';
            else
                normIpsi = app.an_Single.contrastIpsiImg; % As of 08-2023, these are background subtracted images (kept old name for compatibility)
                normContra = app.an_Single.contrastContraImg;
                app.rDistIntensityType = 'backgroundSubtracted';
            end
            %% Set filename indicator
            app.IpsifilenameEditField.Value = regexprep(app.an_Single.ipsiName, 'ipsi/contra', '', 'ignorecase');
            %% Find overlap mask, then remove those overlap pixels from ipsi and contra (So a true pixel in ipsi mask is not counted as both ipsi AND overlap)
            app.an_ipsiRemBin = app.an_Single.binIpsiImg;
            app.an_contraRemBin = app.an_Single.binContraImg;
            app.an_overlapBin = and(app.an_Single.binIpsiImg, app.an_Single.binContraImg); % 1 where overlap occurs, 0 otherwise
            toRemIpsi = and(app.an_Single.binIpsiImg, app.an_overlapBin); % True where both ipsi and overlap == true for same pixel
            toRemContra = and(app.an_Single.binContraImg, app.an_overlapBin);            
            app.an_ipsiRemBin(toRemIpsi) = 0;     % Remove ipsi pixels that also occur in ipsi/contra overlap
            app.an_contraRemBin(toRemContra) = 0; % Remove contra pixels that also occurs in ipsi/contra overlap
            %% Build table with pix and percentages (using binary images)
            app.UITable.RowName = {'Ipsi under ROI';'Contra under ROI'; 'Overlap under ROI'; 'ROI'};           
            app.an_ROIPix = sum(sum(app.an_Single.finalROIMask));
            app.an_ipsiROIPix = sum(sum(app.an_ipsiRemBin));
            app.an_contraROIPix = sum(sum(app.an_contraRemBin));
            app.an_overlapROIPix = sum(sum(app.an_overlapBin));
            app.UITable.Data(1, 1) = app.an_ipsiROIPix;
            app.UITable.Data(1, 2) = 100*(app.an_ipsiROIPix/app.an_ROIPix);
            app.UITable.Data(2, 1) = app.an_contraROIPix;
            app.UITable.Data(2, 2) = 100*(app.an_contraROIPix/app.an_ROIPix);
            app.UITable.Data(3, 1) = app.an_overlapROIPix;
            app.UITable.Data(3, 2) = 100*(app.an_overlapROIPix/app.an_ROIPix);
            app.UITable.Data(4, 1) = app.an_ROIPix;
            app.UITable.Data(4, 2) = 100*(app.an_ROIPix/app.an_ROIPix);
            %% Calculate ipsi/contra ratio for each pixel. Ipsi on Y axis, contra on X axis.
            binI = app.an_Single.binIpsiImg; % For slightly more compact code. Ipsi after ROI removal, contrast adjustment, and binarization
            binC = app.an_Single.binContraImg;
            if app.UsebinaryoverlaponlyCheckBox.Value % Only consider pixels in overlap (as determined by binary images)
                idx = find(and(binI, binC)); % Only pixels in overlap
                app.rDistPixSelected = 'overlapOnly';
            else % Use entire ROI
                idx = find(app.an_Single.finalROIMask); % All pixels inside ROI
                app.rDistPixSelected = 'allInsideROI';
            end
            if numel(idx) == 0
                uialert(app.UIFigure, 'Check if there are no overlap pixels (generate type 1 tiled overlap figure).', 'No pixels to process');
                app.UsebinaryoverlaponlyCheckBox.Value = 0;
                return
            end
            app.an_overlapArray = [];
            app.an_overlapArray(:, 1) = idx;
            app.an_overlapArray(:, 2) = normIpsi(idx);
            app.an_overlapArray(:, 3) = normContra(idx);     
            %% Get R distribution from above and calculate variance, std, and N
            app.an_RLogArray = [];
            skipCounter = 0;
            changeCounter = 0;
            fC = 0;
            for R = 1:size(app.an_overlapArray, 1) % For each row in ipsi/contra per pix array
                if (app.an_overlapArray(R, 2) == 0) && (app.an_overlapArray(R, 3) == 0) % Skip counter (only when both pixels have value 0) (11-2023 change)
                    skipCounter = skipCounter+1;
                    continue;
                end
                if (app.an_overlapArray(R, 2) == 0) || (app.an_overlapArray(R, 3) == 0) % Only one pixel exactly 0 from pair. Change that zero to a one (11-2023 change)
                    changeCounter = changeCounter + 1;
                    if app.an_overlapArray(R, 2) == 0
                        app.an_overlapArray(R, 2) = 1;
                    else % It's the other pixel, change that
                        app.an_overlapArray(R, 3) = 1;
                    end
                end
                Rval = log10(app.an_overlapArray(R, 2)/app.an_overlapArray(R, 3)); % log base 10(ipsiIntensity/contraIntensity) for current pixel
                if (isinf(Rval) || isnan(Rval))
                    skipCounter = skipCounter+1;
                    continue;
                end
                fC = fC+1;
                app.an_RLogArray(fC, 1) = Rval;                
            end
            app.RdistributionvarianceEditField.Value = 0;
            app.RdistributionvarianceEditField.Value = var(app.an_RLogArray);
            app.RdistributionstandarddeviationEditField.Value = 0;
            app.RdistributionstandarddeviationEditField.Value = std(app.an_RLogArray);
            app.PixelsusedforcalculationEditField.Value = 0;
            app.PixelsusedforcalculationEditField.Value = size(app.an_RLogArray, 1);
            app.NumberofskippedpixelsEditField.Value = 0;          
            app.NumberofskippedpixelsEditField.Value = skipCounter;
            app.NumberofchangedpixelsEditField.Value = 0;
            app.NumberofchangedpixelsEditField.Value = changeCounter;
        end


        function drawRawCompositeToAxes_F(app)
            % Small helper to contain this code (used by select ipsi/contra
            % image buttons). 08-2023 addition
            if app.Switch.Value % Left value = green ipsi, magenta contra
                app.drawImgToAxes_F(imfuse(app.rawIpsiIm, app.rawContraIm, 'ColorChannel', 'green-magenta')); % Draw green/magenta composite to axes by default
            else % Right value = green contra, magenta ipsi
                app.drawImgToAxes_F(imfuse(app.rawContraIm, app.rawIpsiIm, 'ColorChannel', 'green-magenta'));
            end
        end
        
        function clearRawImages_F(app)
            % If one of the images doesn't match the other in size, 
            % clear both and reset lamps/flags
            app.rawIpsiIm = [];
            app.rawContraIm = [];
            app.ipsiSetLamp.Color = [1 0 1];
            app.contraSetLamp.Color = [1 0 1];
            app.ipsiSelected = false;
            app.contraSelected = false;
            app.ipsiTextName.Value = '';
            app.contraTextName.Value = '';
        end
        
        
        function resetBackSubPanel_F(app)
            % Reset all values in background subtraction panel and reset
            % background mask etc. To default values if applicable
            app.backgroundMask = [];
            app.backgroundStatsTable.Data = [];
            app.Enteripsithresholdtouse1255EditField.Value = 10;
            app.Entercontrathresholdtouse1255EditField.Value = 10;          
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Initializing', 'Message', 'Initializing, please wait...', 'Indeterminate', 'on');
            %{
            %app.UIFigure.WindowState = 'maximized'; % Commenting out since
            %there is sometimes an issue where the axes don't resize
            %properly after maximization??? 1-27-2022
            %}
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            app.drawImgToAxes_F([]); % Clear axes
            addStyle(app.UITable, uistyle('HorizontalAlignment', 'left'));
            app.defaultPath = matlabroot; % Default as matlab root for input/output path. Changed on any valid new input/output path
            drawnow;
            close(progBar);
        end

        % Button pushed function: DrawmaskButton
        function DrawmaskButtonPushed(app, event)
            app.resetPanelsAndLamps_F('Mask', [1 0 1]);
            app.DrawmaskButton.Enable = 'off';
            app.RefinemaskButton.Enable = 'off';
            app.BackgroundsubtractionPanel.Enable = 'off';
            if app.Switch.Value % Left value, green ipsi magenta contra
                app.drawImgToAxes_F(imfuse(app.rawIpsiIm, app.rawContraIm, 'ColorChannel', 'green-magenta'));
            else % Magenta contra, green ipsi
                app.drawImgToAxes_F(imfuse(app.rawContraIm, app.rawIpsiIm, 'ColorChannel', 'green-magenta'));
            end
            app.ROIMask = [];
            app.ROIMask_Fin = [];
            app.ROIHandle = [];
            app.ROIHandle = images.roi.Freehand(app.UIAxes, 'LineWidth', 1);
            draw(app.ROIHandle); % Continues once finished drawing
            app.ROIMask = createMask(app.ROIHandle); % Binary image mask
            app.MasksetLamp.Color = [0 1 0];
            app.MaskingPanel.Enable = 'on';
            app.DrawmaskButton.Enable = 'on';
            app.RefinemaskButton.Enable = 'on';
        end

        % Button pushed function: RefinemaskButton
        function RefinemaskButtonPushed(app, event)
            if isvalid(app.ROIHandle)
                app.ROIMask = createMask(app.ROIHandle); % Regenerate mask (in case user edited ROI after drawing it)
            end
            app.FinalROIgeneratedLamp.Color = [1 0 1];
            if app.Switch.Value % Left value, green ipsi magenta contra
                app.drawImgToAxes_F(imfuse(app.rawIpsiIm, app.rawContraIm, 'ColorChannel', 'green-magenta'));
            else % Magenta contra, green ipsi
                app.drawImgToAxes_F(imfuse(app.rawContraIm, app.rawIpsiIm, 'ColorChannel', 'green-magenta'));
            end          
            app.ROIMask_Fin = [];
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Processing contour', ...
                'Message', 'Refining outline of mask. Please wait...', 'Indeterminate', 'on');
            BW = activecontour(app.currShown, app.ROIMask, app.ContouriterationsEditField.Value, ... % Assumes green-magenta image is app.currShown
                'Chan-Vese', 'ContractionBias', app.ContractionbiasSlider.Value, ...
                'SmoothFactor', app.SmoothfactorSlider.Value);
            app.ROIMask_Fin = bwareafilt(BW, 1); % In case multiple ROI drawn, only keep largest
            perim = bwperim(app.ROIMask_Fin);
            wOverlay = imoverlay(app.currShown, perim, 'r');
            app.drawImgToAxes_F(wOverlay)
            app.FinalROIgeneratedLamp.Color = [0 1 0];
            app.BackgroundsubtractionPanel.Enable = 'on';
            close(progBar);
        end

        % Button pushed function: SavewhatisshowntoanimageButton_MaskTab
        function SavewhatisshowntoanimageButton_MaskTabPushed(app, event)
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Writing image', ...
                'Message', 'Writing image to file. Please wait...', 'Indeterminate', 'on');
            app.SavewhatisshowntoanimageButton_MaskTab.Enable = 'off';
            [file, path] = uiputfile({'*.png';'*.bmp';'*.tif'}, 'Saving current display to image file', app.defaultPath);
            if file == 0
                app.SavewhatisshowntoanimageButton_MaskTab.Enable = 'on';
                return; 
            end
            app.defaultPath = path;
            imwrite(app.currShown, fullfile(path, file));
            app.SavewhatisshowntoanimageButton_MaskTab.Enable = 'on';
            drawnow;
            close(progBar);
        end

        % Button pushed function: SaveasmatButton
        function SaveasmatButtonPushed(app, event)
            app.SaveasmatButton.Enable = 'off';
            %% User input for where to put file
            [file, path] = uiputfile('*.mat', 'Save masking data as .mat file', app.defaultPath);
            if path == 0; app.SaveasmatButton.Enable = 'on'; return; end
            app.defaultPath = path;
            fullMatPath = fullfile(path, file); % Includes .mat extension in 'file'
            %% Build data struct for saving
            imStruct.dateGenerated = datetime('now');
            imStruct.ipsiIsGreen = app.Switch.Value; % 1 = green ipsi, magenta contra. 0 = magenta contra, green ipsi.
            imStruct.ipsiName = app.currIpsiName;
            imStruct.contraName = app.currContraName;
            imStruct.rawIpsiImg = app.rawIpsiIm;
            imStruct.rawContraImg = app.rawContraIm;
            imStruct.finalROIMask = app.ROIMask_Fin;
            imStruct.backgroundROIMask = app.backgroundMask;
            imStruct.contrastIpsiImg = app.contrastIpsiIm; % !! Note that as of august 2023, this contains the background subtracted data (same name for compatibility reasons)
            imStruct.contrastContraImg = app.contrastContraIm; % !! Note that as of august 2023, this contains the background subtracted data (same name for compatibility reasons)
            imStruct.note = 'contrastIpsiImg and contrastContraImg store the background subtracted images. Old names kept for compatibility (aug 2023)';
            imStruct.binThreshIpsi = app.binThreshIpsi; % Threshold from background subtraction (used to binarize as well). As of 08-2023
            imStruct.binThreshContra = app.binThreshContra;
            imStruct.binIpsiImg = app.binIpsiIm;
            imStruct.binContraImg = app.binContraIm;
            %%
            save(fullMatPath, 'imStruct');
            app.SaveasmatButton.Enable = 'on';
        end

        % Button pushed function: SelectmatButton
        function SelectmatButtonPushed(app, event)
            %% Load data struct into app.m_Single
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Loading', ...
                'Message', 'Loading and analyzing .mat. Please wait...', ...
                'Indeterminate', 'on');
            app.FigGenTabs.Visible = 'off';
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Enable = "off";
            app.resetPanelsAndLamps_F('Mask', [1 0 1]);
            app.resetPanelsAndLamps_F('Back', [1 0 1]);
            [file, path] = uigetfile({'*.mat'}, 'Pick mat to load', app.defaultPath);
            if file == 0; close(progBar); return; end
            app.defaultPath = path;
            app.an_Single = [];
            temp = load(fullfile(path, file));
            app.an_Single = temp.imStruct;
            if ~isfield(app.an_Single, 'backgroundROIMask')
                uialert(app.UIFigure, 'This mat does not contain a backgroundROIMask (old version). Regenerate this mat with the analyze tab using the current app version', 'No background ROI', 'Icon', 'error');
                app.an_Single = [];
                return;
            end
            if ~isa(app.an_Single.rawIpsiImg, 'uint8') % old data stored as uint16, warn user if not 8 bit and convert to 8 bit
                uialert(app.UIFigure, 'Image data not stored as 8-bit grayscale; converting to 8-bit', 'Old data conversion', 'Icon', 'info');
                app.an_Single.rawIpsiImg = im2uint8(app.an_Single.rawIpsiImg);
                app.an_Single.rawContraImg = im2uint8(app.an_Single.rawContraImg);
                app.an_Single.contrastIpsiImg = im2uint8(app.an_Single.contrastIpsiImg);
                app.an_Single.contrastContraImg = im2uint8(app.an_Single.contrastContraImg);              
            end
            clearvars temp
            app.analyzeMat_F();
            app.ShowrawcompositeButtonPushed(); % Draw raw composite
            app.FigGenTabs.Visible = 'on';
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Enable = "on";
            drawnow;
            close(progBar);
        end

        % Button pushed function: Showtiledoverlaptype2Button
        function Showtiledoverlaptype2ButtonPushed(app, event)
            close all
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Generating image', ...
                'Message', 'Generating tiled plot (type 2). Please wait...', ...
                'Indeterminate', 'on');
            overlapColor = [1 1 0];
            nR = size(app.an_Single.rawIpsiImg, 1);
            nC = size(app.an_Single.rawIpsiImg, 2);
            blackImg = zeros(nR, nC);
            if app.an_Single.ipsiIsGreen
                iF = imfuse(app.an_Single.rawIpsiImg, blackImg, 'ColorChannel', 'green-magenta');   % Green ipsi
                cF = imfuse(blackImg, app.an_Single.rawContraImg, 'ColorChannel', 'green-magenta'); % Magenta contra
                rawOverlap = imfuse(app.an_Single.rawIpsiImg, app.an_Single.rawContraImg, 'ColorChannel', 'green-magenta');
                binColorOverlap = imoverlay(blackImg, app.an_contraRemBin, [1 0 1]); % Magenta contra RGB = [1 0 1]
                binColorOverlap = imoverlay(binColorOverlap, app.an_ipsiRemBin, [0 1 0]); % Green ipsi;
            else
                iF = imfuse(blackImg, app.an_Single.rawIpsiImg, 'ColorChannel', 'green-magenta');   % Magenta ipsi
                cF = imfuse(app.an_Single.rawContraImg, blackImg, 'ColorChannel', 'green-magenta'); % Green contra
                rawOverlap = imfuse(app.an_Single.rawContraImg, app.an_Single.rawIpsiImg, 'ColorChannel', 'green-magenta');
                binColorOverlap = imoverlay(blackImg, app.an_contraRemBin, [0 1 0]); % green contra RGB = [1 0 1]
                binColorOverlap = imoverlay(binColorOverlap, app.an_ipsiRemBin, [1 0 1]); % Green ipsi;
            end
            binColorOverlap = imoverlay(binColorOverlap, app.an_overlapBin, overlapColor);
            cellIn = {cF, iF, rawOverlap, binColorOverlap};
            montage(cellIn, 'Size', [1 4], 'ThumbnailSize', []);
            close(progBar);
        end

        % Button pushed function: Showtiledoverlaptype1Button
        function Showtiledoverlaptype1ButtonPushed(app, event)
            close all
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Generating image', ...
                'Message', 'Generating tiled plot (type 1). Please wait...', ...
                'Indeterminate', 'on');
            overlapColor = [1 1 0];
            perimColor = [1 1 1];
            nR = size(app.an_Single.rawIpsiImg, 1);
            nC = size(app.an_Single.rawIpsiImg, 2);
            blackImg = zeros(nR, nC);
            if app.an_Single.ipsiIsGreen
                iF = imfuse(app.an_Single.rawIpsiImg, blackImg, 'ColorChannel', 'green-magenta');   % Green ipsi
                cF = imfuse(blackImg, app.an_Single.rawContraImg, 'ColorChannel', 'green-magenta'); % Magenta contra
            else
                iF = imfuse(blackImg, app.an_Single.rawIpsiImg, 'ColorChannel', 'green-magenta');   % Magenta ipsi
                cF = imfuse(app.an_Single.rawContraImg, blackImg, 'ColorChannel', 'green-magenta'); % Green contra
            end
            overlap = imoverlay(blackImg, app.an_overlapBin, overlapColor);
            roiPerim = bwperim(app.an_Single.finalROIMask); % Mask -> binary perimeter (single pixel wide)
            overlap = imoverlay(overlap, roiPerim, perimColor); 
            cellIn = {cF, iF, overlap};
            montage(cellIn, 'Size', [1 3], 'ThumbnailSize', []);
            drawnow;
            close(progBar);
        end

        % Button pushed function: ScatteripsivscontraButton
        function ScatteripsivscontraButtonPushed(app, event)
            close all
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Generating scatter', ...
                'Message', 'Generating ipsi/contra intensity scatter. Please wait...', ...
                'Indeterminate', 'on');
            ax = axes;
            dC = app.DotsizeEditField.Value; % Dot size
            c = app.ScatterDotColor.Color; % Dot color
            if app.UsebinaryoverlaponlyCheckBox.Value
                oPart = '-overlapOnly';
            else
                oPart = '-entireROI';
            end
            if app.UserawipsicontraCheckBox.Value
                cPart = '-raw images';
            else
                cPart = '-back sub images';
            end
            cTitle = strcat(app.IpsifilenameEditField.Value, oPart, cPart);
            scatter(ax, app.an_overlapArray(:, 3), app.an_overlapArray(:, 2), dC, c, 'filled');
            xlim(ax, [0 255]); % 08-2023 change (now forced into 8-bit grayscale)
            ylim(ax, [0 255]);
            xlabel(ax, 'Contra intensity');
            ylabel(ax, 'Ipsi intensity');
            title(ax, cTitle);
            drawnow;
            close(progBar);
        end

        % Button pushed function: SetdotcolorButton
        function SetdotcolorButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.ScatterDotColor.Color = setColor;
            end
        end

        % Button pushed function: ShowRdistributionButton
        function ShowRdistributionButtonPushed(app, event)
            % Jaubert paper is histogram with bin size = 0.1 log units
            % Contained in app.m_RLogArray (set on Selectmat callback)
            % When changing the ylimit later (from .fig file), use 
            % ylim([0 .10]) for 0 to 10% (as format)
            % Use property inspector in figure toolbar to change line color etc
            close all
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Generating plot', ...
                'Message', 'Generating R distribution. Please wait...', ...
                'Indeterminate', 'on');
            ax = axes;
            [N, edges] = histcounts(app.an_RLogArray, 'BinWidth', ...
                 app.BinsizelogunitsEditField.Value, 'Normalization', 'probability');
            Xvals = [edges(1):app.BinsizelogunitsEditField.Value:edges(end-1)]; %#ok<NBRAK> % Using left edge of bins for X vals
            %{
            % DEV - Writing raw R values to excel
            [file, path] = uiputfile('*.xlsx');
            if file == 0; return; end
            fullOutPath = fullfile(path, file);
            writematrix(Xvals, fullOutPath);
            %}
            plot(ax, Xvals, N*100); % N*100 to get to percent
            binPart = num2str(app.BinsizelogunitsEditField.Value);
            if app.UsebinaryoverlaponlyCheckBox.Value
                oPart = '-overlapOnly';
            else
                oPart = '-entireROI';
            end
            if app.UserawipsicontraCheckBox.Value
                cPart = '-raw images';
            else
                cPart = '-back sub images';
            end
            axTitle = strcat(app.IpsifilenameEditField.Value, ...
                '-log10(ipsiI/contraI) per pixel - Bin size (log units)=', ...
                binPart, oPart, cPart);
            title(ax, axTitle);
            ylabel(ax, 'Relative probability (%)');
            xlabel(ax, 'Log units');
            xlim([-2.5 2.5]); % This and xticks to match format of jaubert-miazza 2005 paper (see figure 3B)
            xticks(-2:2);
            drawnow;
            close(progBar);
        end

        % Value changed function: UserawipsicontraCheckBox
        function UserawipsicontraCheckBoxValueChanged(app, event)
            close all % Close any open scatter etc plots
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Recalculating data', ...
                'Message', 'Recalculating data. Please wait...', ...
                'Indeterminate', 'on');
            app.analyzeMat_F();
            drawnow;
            close(progBar);
        end

        % Value changed function: UsebinaryoverlaponlyCheckBox
        function UsebinaryoverlaponlyCheckBoxValueChanged(app, event)
            close all % Close any open scatter etc plots
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Recalculating data', ...
                'Message', 'Recalculating data. Please wait...', ...
                'Indeterminate', 'on');
            app.analyzeMat_F();       
            drawnow;
            close(progBar);
        end

        % Button pushed function: ShowrawipsiButton
        function ShowrawipsiButtonPushed(app, event)
            app.drawImgToAxes_F(app.an_Single.rawIpsiImg);
        end

        % Button pushed function: ShowrawcontraButton
        function ShowrawcontraButtonPushed(app, event)
            app.drawImgToAxes_F(app.an_Single.rawContraImg)
        end

        % Button pushed function: ShowrawcompositeButton
        function ShowrawcompositeButtonPushed(app, event)
            if app.an_Single.ipsiIsGreen
                app.drawImgToAxes_F(imfuse(app.an_Single.rawIpsiImg, app.an_Single.rawContraImg, 'ColorChannel', 'green-magenta'));
            else
                app.drawImgToAxes_F(imfuse(app.an_Single.rawContraImg, app.an_Single.rawIpsiImg, 'ColorChannel', 'green-magenta'));
            end
        end

        % Button pushed function: ShowbackgroundsubtractedipsiButton
        function ShowbackgroundsubtractedipsiButtonPushed(app, event)
            app.drawImgToAxes_F(app.an_Single.contrastIpsiImg);
        end

        % Button pushed function: ShowbackgroundsubtractedcontraButton
        function ShowbackgroundsubtractedcontraButtonPushed(app, event)
            app.drawImgToAxes_F(app.an_Single.contrastContraImg);
        end

        % Button pushed function: ShowbackgroundsubtractedcompositeButton
        function ShowbackgroundsubtractedcompositeButtonPushed(app, event)
            if app.an_Single.ipsiIsGreen
                toDraw = imfuse(app.an_Single.contrastIpsiImg, app.an_Single.contrastContraImg, 'ColorChannel', 'green-magenta');
            else
                toDraw = imfuse(app.an_Single.contrastContraImg, app.an_Single.contrastIpsiImg, 'ColorChannel', 'green-magenta');
            end
            app.drawImgToAxes_F(toDraw);
        end

        % Button pushed function: SelectcontraimageButton
        function SelectcontraimageButtonPushed(app, event)
            app.contraSelected = false;
            app.contraSetLamp.Color = [1 0 1];
            app.rawContraIm = [];
            app.resetPanelsAndLamps_F('Mask', [1 0 1]);
            app.resetPanelsAndLamps_F('Back', [1 0 1]);
            app.MaskingPanel.Enable = 'off';
            app.BackgroundsubtractionPanel.Enable = 'off';
            app.MaskingPanel.Enable = 'off';
            app.contraTextName.Value = '';
            app.drawImgToAxes_F([]);
            app.resetBackSubPanel_F();
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Loading', ...
                'Message', 'Loading contra image. Please wait...', ...
                'Indeterminate', 'on');
            [file, path] = uigetfile({'*.tif;*.tiff'}, 'Select contra image', app.defaultPath);
            if file == 0 % User cancelled prompt
                close(progBar); 
                app.rawContraIm = [];
                app.contraSelected = false;                
                return; 
            end
            app.defaultPath = path;
            contraImg = imread(fullfile(path, file));
            if ~isa(contraImg, 'uint8')
                uialert(app.UIFigure, 'Contra image not stored as 8-bit grayscale; converting to 8-bit', 'Type conversion', 'Icon', 'info');
                contraImg = im2uint8(contraImg);
            end
            app.rawContraIm = contraImg;
            app.contraTextName.Value = file;
            app.contraSetLamp.Color = [0 1 0];
            app.contraSelected = true;
            app.currContraName = file;
            if and(app.ipsiSelected, app.contraSelected) % Both images selected, show raw composite
                if ~isequal(size(app.rawIpsiIm), size(app.rawContraIm))
                    beep;
                    uialert(app.UIFigure, 'Your ipsi and contra images are not the same size', 'Image size mismatch');
                    app.clearRawImages_F();
                    return
                end
                
                app.drawRawCompositeToAxes_F();
                app.MaskingPanel.Enable = 'on';
            end
        end

        % Button pushed function: SelectipsiimageButton
        function SelectipsiimageButtonPushed(app, event)
            app.ipsiSelected = false;
            app.ipsiSetLamp.Color = [1 0 1];
            app.rawIpsiIm = [];
            app.resetPanelsAndLamps_F('Mask', [1 0 1]);
            app.resetPanelsAndLamps_F('Back', [1 0 1]);
            app.MaskingPanel.Enable = 'off';
            app.ipsiTextName.Value = '';
            app.drawImgToAxes_F([]);
            app.resetBackSubPanel_F();
            app.BackgroundsubtractionPanel.Enable = 'off';
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Loading', ...
                'Message', 'Loading ipsi image. Please wait...', ...
                'Indeterminate', 'on');
            [file, path] = uigetfile({'*.tif;*.tiff'}, 'Select ipsi image', app.defaultPath);
            if file == 0 % User cancelled prompt
                close(progBar);
                app.rawIpsiIm = [];
                app.ipsiSelected = false;
                return; 
            end
            app.defaultPath = path;
            ipsiImg = imread(fullfile(path, file));
            if ~isa(ipsiImg, 'uint8')
                uialert(app.UIFigure, 'Ipsi image not stored as 8-bit grayscale; converting to 8-bit', 'Type conversion', 'Icon', 'info');
                ipsiImg = im2uint8(ipsiImg);
            end
            app.rawIpsiIm = ipsiImg;
            app.ipsiTextName.Value = file;
            app.ipsiSetLamp.Color = [0 1 0];
            app.ipsiSelected = true;
            app.currIpsiName = file;
            if and(app.ipsiSelected, app.contraSelected) % Both images selected, show raw composite
                if ~isequal(size(app.rawIpsiIm), size(app.rawContraIm))
                    beep;
                    uialert(app.UIFigure, 'Your ipsi and contra images are not the same size', 'Image size mismatch');
                    app.clearRawImages_F();
                    return
                end
                app.drawRawCompositeToAxes_F();
                app.MaskingPanel.Enable = 'on';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if and(app.ipsiSelected, app.contraSelected) % Both images selected, show raw composite
                app.drawRawCompositeToAxes_F();
            end           
        end

        % Button pushed function: DrawROIforbackgroundButton
        function DrawROIforbackgroundButtonPushed(app, event)
            app.backgroundStatsTable.Data = [];
            app.resetPanelsAndLamps_F('Back', [1 0 1]);
            app.drawImgToAxes_F(app.rawIpsiIm);
            backgroundROIHandle = images.roi.Freehand(app.UIAxes, 'LineWidth', 1);
            draw(backgroundROIHandle); % Continues once finished drawing
            app.backgroundMask = createMask(backgroundROIHandle); % Binary image mask
            % Populate ipsi/contra background stats
            ipsiBackgroundPixValues = app.rawIpsiIm(app.backgroundMask);
            contraBackgroundPixValues = app.rawContraIm(app.backgroundMask);
            app.BackgroundROIsetLamp.Color = [0 1 0];
            app.backgroundStatsTable.RowName = {'Ipsi background';'Contra background'};
            app.backgroundStatsTable.Data(1, 1) = mean(ipsiBackgroundPixValues);          % Ipsi background mean
            app.backgroundStatsTable.Data(1, 2) = min(ipsiBackgroundPixValues);           % Ipsi background min
            app.backgroundStatsTable.Data(1, 3) = max(ipsiBackgroundPixValues);           % Ipsi background max
            app.backgroundStatsTable.Data(1, 4) = std(single(ipsiBackgroundPixValues));   % Ipsi background std. std() can't take uint8, so converting to single here
            app.backgroundStatsTable.Data(2, 1) = mean(contraBackgroundPixValues);        % Contra background mean
            app.backgroundStatsTable.Data(2, 2) = min(contraBackgroundPixValues);         % Contra background min
            app.backgroundStatsTable.Data(2, 3) = max(contraBackgroundPixValues);         % Contra background max
            app.backgroundStatsTable.Data(2, 4) = std(single(contraBackgroundPixValues)); % Contra background std. std() can't take uint8, so converting to single here
            app.Entercontrathresholdtouse1255EditFieldValueChanged(app);
            app.Enteripsithresholdtouse1255EditFieldValueChanged(app);
            app.SaveasmatButton.Enable = 'on';
        end

        % Button pushed function: ShowrawipsiandhistogramButton
        function ShowrawipsiandhistogramButtonPushed(app, event)
            close all
            app.drawImgToAxes_F(app.rawIpsiIm);
            figure;
            histogram(app.rawIpsiIm);
            if app.UselogYaxisforhistogramsCheckBox.Value % Generate with log Y axis
                set(gca, 'YScale', 'log');
            end
            ylabel('Pixel count');
            xlabel('Brightness (8-bit)');
            title('Raw ipsi image histogram (no background subtraction)');
        end

        % Button pushed function: ShowrawcontraandhistogramButton
        function ShowrawcontraandhistogramButtonPushed(app, event)
            close all
            app.drawImgToAxes_F(app.rawContraIm);
            figure;
            histogram(app.rawContraIm);
            if app.UselogYaxisforhistogramsCheckBox.Value % Generate with log Y axis
                set(gca, 'YScale', 'log');
            end
            ylabel('Pixel count');
            xlabel('Brightness (8-bit)');
            title('Raw contra image histogram (no background subtraction)');
        end

        % Button pushed function: 
        % ShowbackgroundsubtractedipsiandhistogramButton
        function ShowbackgroundsubtractedipsiandhistogramButtonPushed(app, event)
            % Also sets background subtraction variables (does the
            % background subtraction). Contra counterpart does the same for
            % contra
            close all
            app.drawImgToAxes_F(app.contrastIpsiIm);
            figure;
            histogram(app.contrastIpsiIm);
            if app.UselogYaxisforhistogramsCheckBox.Value % Generate with log Y axis
                set(gca, 'YScale', 'log');
            end
            ylabel('Pixel count');
            xlabel('Brightness (8-bit)');
            title('Background subtracted ipsi');
        end

        % Value changed function: Enteripsithresholdtouse1255EditField
        function Enteripsithresholdtouse1255EditFieldValueChanged(app, event)
           app.binThreshIpsi = app.Enteripsithresholdtouse1255EditField.Value;
           % Background subtraction + normalization
           app.contrastIpsiIm = app.rawIpsiIm - app.binThreshIpsi;
           app.contrastIpsiIm(app.contrastIpsiIm < 0) = 0;
           % Binarization
           app.binIpsiIm = false(size(app.rawIpsiIm));
           app.binIpsiIm(app.contrastIpsiIm > app.binThreshIpsi) = true;
           app.binIpsiIm(not(app.ROIMask_Fin)) = false; % Set all pix outside main ROI to false
        end

        % Value changed function: Entercontrathresholdtouse1255EditField
        function Entercontrathresholdtouse1255EditFieldValueChanged(app, event)
            app.binThreshContra = app.Entercontrathresholdtouse1255EditField.Value;
            % Background subtraction + normalization
            app.contrastContraIm = app.rawContraIm - app.binThreshContra; % Background subtraction
            app.contrastContraIm(app.contrastContraIm < 0) = 0;
            % Binarization
            app.binContraIm = false(size(app.rawContraIm));
            app.binContraIm(app.contrastContraIm > app.binThreshContra) = true;
            app.binContraIm(not(app.ROIMask_Fin)) = false; % Set all pix outside main ROI to false
        end

        % Button pushed function: 
        % ShowbackgroundsubtractedcontraandhistogramButton
        function ShowbackgroundsubtractedcontraandhistogramButtonPushed(app, event)
            % Also sets background subtraction variables (does the
            % background subtraction). Ipsi counterpart does the same for
            % Ipsi
            close all
            app.drawImgToAxes_F(app.contrastContraIm);
            figure;
            histogram(app.contrastContraIm);
            if app.UselogYaxisforhistogramsCheckBox.Value % Generate with log Y axis
                set(gca, 'YScale', 'log');
            end
            ylabel('Pixel count');
            xlabel('Brightness (8-bit)');
            title('Background subtracted contra');
        end

        % Button pushed function: SaveRdistributionpixeldatatoexcelButton
        function SaveRdistributionpixeldatatoexcelButtonPushed(app, event)
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Writing data', ...
                'Message', 'Creating excel file. Please wait...', ...
                'Indeterminate', 'on');
            filter = {'*.xlsx'};
            [file, path] = uiputfile(filter, 'Write R dist pixel data', app.defaultPath);
            if file == 0
                close(progBar);
                return; 
            end
            app.defaultPath = path;
            xlsPath = fullfile(path, file);          
            pixIdx = app.an_overlapArray(:, 1);
            ipsiIntensity = app.an_overlapArray(:, 2);
            contraIntensity = app.an_overlapArray(:, 3);
            pixelIntensityTypeBelow = {app.rDistIntensityType}; % 'raw' or 'backgroundSubtracted'
            whichPixelsUsedBelow = {app.rDistPixSelected}; % 'allInsideROI' or 'overlapOnly'
            binSizeBelow = {app.BinsizelogunitsEditField.Value};
            writetable(table(pixIdx, ipsiIntensity, contraIntensity), xlsPath, 'Sheet', 'Brightness data');
            writetable(table(pixelIntensityTypeBelow, whichPixelsUsedBelow, binSizeBelow), xlsPath, 'Sheet', 'Info');
            close(progBar);
        end

        % Button pushed function: 
        % ShowrawcompositewithROIandbackgroundoutlinedButton
        function ShowrawcompositewithROIandbackgroundoutlinedButtonPushed(app, event)
            if app.an_Single.ipsiIsGreen
                rawComposite = imfuse(app.an_Single.rawIpsiImg, app.an_Single.rawContraImg, 'ColorChannel', 'green-magenta');
            else
                rawComposite = imfuse(app.an_Single.rawContraImg, app.an_Single.rawIpsiImg, 'ColorChannel', 'green-magenta');
            end
            roiPerim = bwperim(app.an_Single.finalROIMask);
            backPerim = bwperim(app.an_Single.backgroundROIMask);
            withMainROI = imoverlay(rawComposite, roiPerim, 'yellow');
            withBackAdded = imoverlay(withMainROI, backPerim, 'cyan');
            app.drawImgToAxes_F(withBackAdded);
        end

        % Button pushed function: SaveRdistribtionplotdatatoexcelButton
        function SaveRdistribtionplotdatatoexcelButtonPushed(app, event)
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Writing data', ...
                'Message', 'Creating excel file. Please wait...', ...
                'Indeterminate', 'on');
            filter = {'*.xlsx'};
            [file, path] = uiputfile(filter, 'Write R dist plot data', app.defaultPath);
            if file == 0
                close(progBar);
                return;
            end
            app.defaultPath = path;
            xlsPath = fullfile(path, file); 
            [N, edges] = histcounts(app.an_RLogArray, 'BinWidth', ...
                 app.BinsizelogunitsEditField.Value, 'Normalization', 'probability');
            Xvals = [edges(1):app.BinsizelogunitsEditField.Value:edges(end-1)]; %#ok<NBRAK> % Using left edge of bins for X vals
            relativePercent = N*100; % Relative percent            
            logUnits = Xvals';
            relativePercent = relativePercent';
            pixelIntensityTypeBelow = {app.rDistIntensityType}; % 'raw' or 'backgroundSubtracted'
            whichPixelsUsedBelow = {app.rDistPixSelected}; % 'allInsideROI' or 'overlapOnly'
            binSizeBelow = {app.BinsizelogunitsEditField.Value};
            writetable(table(logUnits, relativePercent), xlsPath, 'Sheet', 'Rdist data');
            writetable(table(pixelIntensityTypeBelow, whichPixelsUsedBelow, binSizeBelow), xlsPath, 'Sheet', 'Info');
            close(progBar);
        end

        % Button pushed function: SavewhatisshowntoanimageButton_AnalyzeTab
        function SavewhatisshowntoanimageButton_AnalyzeTabPushed(app, event)
            progBar = uiprogressdlg(app.UIFigure, 'Title', 'Writing image', ...
                'Message', 'Writing image to file. Please wait...', 'Indeterminate', 'on');
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Enable = 'off';
            [file, path] = uiputfile({'*.png';'*.bmp';'*.tif'}, 'Saving current display to image file', app.defaultPath);
            if file == 0
                app.SavewhatisshowntoanimageButton_AnalyzeTab.Enable = 'on';
                return; 
            end
            app.defaultPath = path;
            imwrite(app.currShown, fullfile(path, file));
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Enable = 'on';
            drawnow;
            close(progBar);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1005, 1005};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {419, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MaskingcontrastandbinarizationTab = uitab(app.TabGroup);
            app.MaskingcontrastandbinarizationTab.Title = 'Masking, contrast, and binarization';
            app.MaskingPanel = uipanel(app.MaskingcontrastandbinarizationTab);
            app.MaskingPanel.Enable = 'off';
            app.MaskingPanel.Title = 'Masking';
            app.DrawmaskButton = uibutton(app.MaskingPanel, 'push');
            app.DrawmaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmaskButtonPushed, true);
            app.DrawmaskButton.Tooltip = {'Draw the main ROI around the region you want to analyze. The background ROI will be drawn later during "Background subtraction".'};
            app.DrawmaskButton.Text = 'Draw mask';
            app.MasksetLampLabel = uilabel(app.MaskingPanel);
            app.MasksetLampLabel.Text = 'Mask set';
            app.MasksetLamp = uilamp(app.MaskingPanel);
            app.ContractionbiasSliderLabel = uilabel(app.MaskingPanel);
            app.ContractionbiasSliderLabel.Text = 'Contraction bias';
            app.ContractionbiasSlider = uislider(app.MaskingPanel);
            app.ContractionbiasSlider.Limits = [0 1];
            app.ContractionbiasSlider.Value = 0.5;
            app.SmoothfactorSliderLabel = uilabel(app.MaskingPanel);
            app.SmoothfactorSliderLabel.Text = 'Smooth factor';
            app.SmoothfactorSlider = uislider(app.MaskingPanel);
            app.SmoothfactorSlider.Limits = [0 1];
            app.SmoothfactorSlider.Tooltip = {'Smoothing factor for the outline after contraction applied.'};
            app.SmoothfactorSlider.Value = 1;
            app.ContouriterationsEditFieldLabel = uilabel(app.MaskingPanel);
            app.ContouriterationsEditFieldLabel.Text = 'Contour iterations';
            app.ContouriterationsEditField = uieditfield(app.MaskingPanel, 'numeric');
            app.ContouriterationsEditField.Limits = [1 1000];
            app.ContouriterationsEditField.RoundFractionalValues = 'on';
            app.ContouriterationsEditField.ValueDisplayFormat = '%.0f';
            app.ContouriterationsEditField.Value = 50;
            app.FinalROIgeneratedLampLabel = uilabel(app.MaskingPanel);
            app.FinalROIgeneratedLampLabel.Text = 'Final ROI generated';
            app.FinalROIgeneratedLamp = uilamp(app.MaskingPanel);
            app.RefinemaskButton = uibutton(app.MaskingPanel, 'push');
            app.RefinemaskButton.ButtonPushedFcn = createCallbackFcn(app, @RefinemaskButtonPushed, true);
            app.RefinemaskButton.Text = 'Refine mask';
            app.SaveasmatButton = uibutton(app.MaskingcontrastandbinarizationTab, 'push');
            app.SaveasmatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasmatButtonPushed, true);
            app.SaveasmatButton.Enable = 'off';
            app.SaveasmatButton.Tooltip = {'Button will enable once valid ROIs have been generated and background subtraction finished.'};
            app.SaveasmatButton.Text = 'Save as .mat';
            app.SavewhatisshowntoanimageButton_MaskTab = uibutton(app.MaskingcontrastandbinarizationTab, 'push');
            app.SavewhatisshowntoanimageButton_MaskTab.ButtonPushedFcn = createCallbackFcn(app, @SavewhatisshowntoanimageButton_MaskTabPushed, true);
            app.SavewhatisshowntoanimageButton_MaskTab.Tooltip = {'Saves whatever is shown in right panel as an image.'};
            app.SavewhatisshowntoanimageButton_MaskTab.Text = 'Save what is shown to an image';
            app.Switch = uiswitch(app.MaskingcontrastandbinarizationTab, 'slider');
            app.Switch.Items = {'Green ipsi, magenta contra', 'Magenta ipsi, green contra'};
            app.Switch.ItemsData = [1 0];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 1;
            app.SelectcontraimageButton = uibutton(app.MaskingcontrastandbinarizationTab, 'push');
            app.SelectcontraimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcontraimageButtonPushed, true);
            app.SelectcontraimageButton.Text = 'Select contra image';
            app.SelectipsiimageButton = uibutton(app.MaskingcontrastandbinarizationTab, 'push');
            app.SelectipsiimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectipsiimageButtonPushed, true);
            app.SelectipsiimageButton.Text = 'Select ipsi image';
            app.ipsiTextName = uieditfield(app.MaskingcontrastandbinarizationTab, 'text');
            app.ipsiTextName.Editable = 'off';
            app.contraTextName = uieditfield(app.MaskingcontrastandbinarizationTab, 'text');
            app.ipsiSetLamp = uilamp(app.MaskingcontrastandbinarizationTab);
            app.contraSetLamp = uilamp(app.MaskingcontrastandbinarizationTab);
            app.BackgroundsubtractionPanel = uipanel(app.MaskingcontrastandbinarizationTab);
            app.BackgroundsubtractionPanel.Enable = 'off';
            app.BackgroundsubtractionPanel.Title = 'Background subtraction';
            app.DrawROIforbackgroundButton = uibutton(app.BackgroundsubtractionPanel, 'push');
            app.DrawROIforbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIforbackgroundButtonPushed, true);
            app.DrawROIforbackgroundButton.Tooltip = {'Point by point draw an ROI around a background region.'; ''; 'If you want to change this ROI, click this button again. Don''t drag the points on the ROI within the image on the right (won''t update the background ROI).'};
            app.DrawROIforbackgroundButton.Text = 'Draw ROI for background';
            app.backgroundStatsTable = uitable(app.BackgroundsubtractionPanel);
            app.backgroundStatsTable.ColumnName = {'Mean'; 'Min'; 'Max'; 'Std'};
            app.backgroundStatsTable.RowName = {};
            app.backgroundStatsTable.Tooltip = {''};
            app.Enteripsithresholdtouse1255EditFieldLabel = uilabel(app.BackgroundsubtractionPanel);
            app.Enteripsithresholdtouse1255EditFieldLabel.Text = 'Enter ipsi threshold to use (1-255)';
            app.Enteripsithresholdtouse1255EditField = uieditfield(app.BackgroundsubtractionPanel, 'numeric');
            app.Enteripsithresholdtouse1255EditField.Limits = [1 255];
            app.Enteripsithresholdtouse1255EditField.RoundFractionalValues = 'on';
            app.Enteripsithresholdtouse1255EditField.ValueDisplayFormat = '%.0f';
            app.Enteripsithresholdtouse1255EditField.ValueChangedFcn = createCallbackFcn(app, @Enteripsithresholdtouse1255EditFieldValueChanged, true);
            app.Enteripsithresholdtouse1255EditField.Tooltip = {'This value will be subtracted from all pixels. Also used for binarization threshold.'};
            app.Enteripsithresholdtouse1255EditField.Value = 10;
            app.BackgroundROIsetLampLabel = uilabel(app.BackgroundsubtractionPanel);
            app.BackgroundROIsetLampLabel.Text = 'Background ROI set';
            app.BackgroundROIsetLamp = uilamp(app.BackgroundsubtractionPanel);
            app.IpsibackgroundstatsLabel = uilabel(app.BackgroundsubtractionPanel);
            app.IpsibackgroundstatsLabel.Text = 'Background stats';
            app.Entercontrathresholdtouse1255EditFieldLabel = uilabel(app.BackgroundsubtractionPanel);
            app.Entercontrathresholdtouse1255EditFieldLabel.Text = 'Enter contra threshold to use (1-255)';
            app.Entercontrathresholdtouse1255EditField = uieditfield(app.BackgroundsubtractionPanel, 'numeric');
            app.Entercontrathresholdtouse1255EditField.Limits = [1 255];
            app.Entercontrathresholdtouse1255EditField.RoundFractionalValues = 'on';
            app.Entercontrathresholdtouse1255EditField.ValueDisplayFormat = '%.0f';
            app.Entercontrathresholdtouse1255EditField.ValueChangedFcn = createCallbackFcn(app, @Entercontrathresholdtouse1255EditFieldValueChanged, true);
            app.Entercontrathresholdtouse1255EditField.Tooltip = {'This value will be subtracted from all pixels. Also used for binarization threshold.'};
            app.Entercontrathresholdtouse1255EditField.Value = 10;
            app.ShowrawipsiandhistogramButton = uibutton(app.BackgroundsubtractionPanel, 'push');
            app.ShowrawipsiandhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrawipsiandhistogramButtonPushed, true);
            app.ShowrawipsiandhistogramButton.Tooltip = {'Show raw ipsi image plus its pixel brightness distribution.'};
            app.ShowrawipsiandhistogramButton.Text = 'Show raw ipsi and histogram';
            app.ShowrawcontraandhistogramButton = uibutton(app.BackgroundsubtractionPanel, 'push');
            app.ShowrawcontraandhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrawcontraandhistogramButtonPushed, true);
            app.ShowrawcontraandhistogramButton.Tooltip = {'Show raw contra image plus its pixel brightness distribution.'};
            app.ShowrawcontraandhistogramButton.Text = 'Show raw contra and histogram';
            app.ShowbackgroundsubtractedipsiandhistogramButton = uibutton(app.BackgroundsubtractionPanel, 'push');
            app.ShowbackgroundsubtractedipsiandhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbackgroundsubtractedipsiandhistogramButtonPushed, true);
            app.ShowbackgroundsubtractedipsiandhistogramButton.Tooltip = {''};
            app.ShowbackgroundsubtractedipsiandhistogramButton.Text = {'Show background subtracted '; 'ipsi and histogram'};
            app.ShowbackgroundsubtractedcontraandhistogramButton = uibutton(app.BackgroundsubtractionPanel, 'push');
            app.ShowbackgroundsubtractedcontraandhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbackgroundsubtractedcontraandhistogramButtonPushed, true);
            app.ShowbackgroundsubtractedcontraandhistogramButton.Tooltip = {'Show background subtracted contra image plus its pixel brightness distribution.'};
            app.ShowbackgroundsubtractedcontraandhistogramButton.Text = {'Show background subtracted'; ' contra and histogram'};
            app.UselogYaxisforhistogramsCheckBox = uicheckbox(app.BackgroundsubtractionPanel);
            app.UselogYaxisforhistogramsCheckBox.Text = 'Use log Y axis for histograms?';
            app.AnalyzesinglematTab = uitab(app.TabGroup);
            app.AnalyzesinglematTab.Title = 'Analyze single .mat';
            app.SelectmatButton = uibutton(app.AnalyzesinglematTab, 'push');
            app.SelectmatButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmatButtonPushed, true);
            app.SelectmatButton.Text = 'Select .mat';
            app.IpsifilenameEditFieldLabel = uilabel(app.AnalyzesinglematTab);
            app.IpsifilenameEditFieldLabel.Text = 'Ipsi filename';
            app.IpsifilenameEditField = uieditfield(app.AnalyzesinglematTab, 'text');
            app.IpsifilenameEditField.Editable = 'off';
            app.UITable = uitable(app.AnalyzesinglematTab);
            app.UITable.ColumnName = {'# pix'; 'as percent of ROI total'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'Pixel numbers taken from binary images.'};
            app.FigGenTabs = uitabgroup(app.AnalyzesinglematTab);
            app.FigGenTabs.Visible = 'off';
            app.ImagegenerationTab = uitab(app.FigGenTabs);
            app.ImagegenerationTab.Title = 'Image generation';
            app.Showtiledoverlaptype1Button = uibutton(app.ImagegenerationTab, 'push');
            app.Showtiledoverlaptype1Button.ButtonPushedFcn = createCallbackFcn(app, @Showtiledoverlaptype1ButtonPushed, true);
            app.Showtiledoverlaptype1Button.Tooltip = {'1 row x 3 column. Contra, then Ipsi, then overlap with white ROI drawn and overlap pixels in yellow.'; ''; 'Pops out a new figure window. Save from that window if desired.'};
            app.Showtiledoverlaptype1Button.Text = 'Show tiled overlap (type 1)';
            app.Showtiledoverlaptype2Button = uibutton(app.ImagegenerationTab, 'push');
            app.Showtiledoverlaptype2Button.ButtonPushedFcn = createCallbackFcn(app, @Showtiledoverlaptype2ButtonPushed, true);
            app.Showtiledoverlaptype2Button.Tooltip = {'1 row x 4 column. Contra, then ipsi, then raw overlap, then binary overlap (under ROI).'; ''; 'Pops out a new figure window. Save from that window if desired.'};
            app.Showtiledoverlaptype2Button.Text = 'Show tiled overlap (type 2)';
            app.ShowrawipsiButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowrawipsiButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrawipsiButtonPushed, true);
            app.ShowrawipsiButton.Text = 'Show raw ipsi';
            app.ShowrawcontraButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowrawcontraButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrawcontraButtonPushed, true);
            app.ShowrawcontraButton.Text = 'Show raw contra';
            app.ShowbackgroundsubtractedipsiButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowbackgroundsubtractedipsiButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbackgroundsubtractedipsiButtonPushed, true);
            app.ShowbackgroundsubtractedipsiButton.Text = 'Show background subtracted ipsi';
            app.ShowbackgroundsubtractedcontraButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowbackgroundsubtractedcontraButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbackgroundsubtractedcontraButtonPushed, true);
            app.ShowbackgroundsubtractedcontraButton.Text = 'Show background subtracted contra';
            app.ShowrawcompositeButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowrawcompositeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrawcompositeButtonPushed, true);
            app.ShowrawcompositeButton.Text = 'Show raw composite';
            app.ShowbackgroundsubtractedcompositeButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowbackgroundsubtractedcompositeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbackgroundsubtractedcompositeButtonPushed, true);
            app.ShowbackgroundsubtractedcompositeButton.Text = 'Show background subtracted composite';
            app.ShowrawcompositewithROIandbackgroundoutlinedButton = uibutton(app.ImagegenerationTab, 'push');
            app.ShowrawcompositewithROIandbackgroundoutlinedButton.ButtonPushedFcn = createCallbackFcn(app, @ShowrawcompositewithROIandbackgroundoutlinedButtonPushed, true);
            app.ShowrawcompositewithROIandbackgroundoutlinedButton.Text = 'Show raw composite with ROI and background outlined';
            app.PlottingTab = uitab(app.FigGenTabs);
            app.PlottingTab.Title = 'Plotting';
            app.ScatteripsivscontraButton = uibutton(app.PlottingTab, 'push');
            app.ScatteripsivscontraButton.ButtonPushedFcn = createCallbackFcn(app, @ScatteripsivscontraButtonPushed, true);
            app.ScatteripsivscontraButton.Text = 'Scatter ipsi vs contra';
            app.DotsizeEditFieldLabel = uilabel(app.PlottingTab);
            app.DotsizeEditFieldLabel.Text = 'Dot size';
            app.DotsizeEditField = uieditfield(app.PlottingTab, 'numeric');
            app.DotsizeEditField.Limits = [1 Inf];
            app.DotsizeEditField.Value = 10;
            app.ScatterDotColor = uilamp(app.PlottingTab);
            app.SetdotcolorButton = uibutton(app.PlottingTab, 'push');
            app.SetdotcolorButton.ButtonPushedFcn = createCallbackFcn(app, @SetdotcolorButtonPushed, true);
            app.SetdotcolorButton.Text = 'Set dot color';
            app.ShowRdistributionButton = uibutton(app.PlottingTab, 'push');
            app.ShowRdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRdistributionButtonPushed, true);
            app.ShowRdistributionButton.Tooltip = {'Uses histcounts(). Uses leftmost edges of bins for X values. R = log10(ipsiIntensity/contraIntensity) per pixel pair.'; ''; 'If both ipsi and contra intensity are 0 for a pixel, they are both kept at 0. If only one has 0 intensity, that 0 is changed to a 1 to not skew the R distribution.'};
            app.ShowRdistributionButton.Text = 'Show R distribution';
            app.RdistributionvarianceEditFieldLabel = uilabel(app.PlottingTab);
            app.RdistributionvarianceEditFieldLabel.Text = 'R distribution variance';
            app.RdistributionvarianceEditField = uieditfield(app.PlottingTab, 'numeric');
            app.RdistributionvarianceEditField.ValueDisplayFormat = '%11.6g';
            app.RdistributionvarianceEditField.Editable = 'off';
            app.RdistributionstandarddeviationEditFieldLabel = uilabel(app.PlottingTab);
            app.RdistributionstandarddeviationEditFieldLabel.Text = 'R distribution standard deviation';
            app.RdistributionstandarddeviationEditField = uieditfield(app.PlottingTab, 'numeric');
            app.RdistributionstandarddeviationEditField.ValueDisplayFormat = '%11.6g';
            app.RdistributionstandarddeviationEditField.Editable = 'off';
            app.PixelsusedforcalculationEditFieldLabel = uilabel(app.PlottingTab);
            app.PixelsusedforcalculationEditFieldLabel.Text = 'Pixels used for calculation';
            app.PixelsusedforcalculationEditField = uieditfield(app.PlottingTab, 'numeric');
            app.PixelsusedforcalculationEditField.Limits = [0 Inf];
            app.PixelsusedforcalculationEditField.RoundFractionalValues = 'on';
            app.PixelsusedforcalculationEditField.ValueDisplayFormat = '%i';
            app.PixelsusedforcalculationEditField.Editable = 'off';
            app.PixelsusedforcalculationEditField.Tooltip = {'Number of pixels used for the R distribution. Original number of pixels minus the number of skipped pixels.'};
            app.BinsizelogunitsEditFieldLabel = uilabel(app.PlottingTab);
            app.BinsizelogunitsEditFieldLabel.Text = 'Bin size (log-units)';
            app.BinsizelogunitsEditField = uieditfield(app.PlottingTab, 'numeric');
            app.BinsizelogunitsEditField.Limits = [0.0001 Inf];
            app.BinsizelogunitsEditField.Tooltip = {'Bin size used to generate the X axis of the R distribution plot.'};
            app.BinsizelogunitsEditField.Value = 0.1;
            app.NumberofskippedpixelsEditFieldLabel = uilabel(app.PlottingTab);
            app.NumberofskippedpixelsEditFieldLabel.Text = 'Number of skipped pixels';
            app.NumberofskippedpixelsEditField = uieditfield(app.PlottingTab, 'numeric');
            app.NumberofskippedpixelsEditField.Limits = [0 Inf];
            app.NumberofskippedpixelsEditField.RoundFractionalValues = 'on';
            app.NumberofskippedpixelsEditField.ValueDisplayFormat = '%i';
            app.NumberofskippedpixelsEditField.Editable = 'off';
            app.NumberofskippedpixelsEditField.Tooltip = {'Count of pixels where both ipsi and contra for that pixel were exactly 0 intensity, or where log(ipsi/contra) for that pixel returned NaN or Inf values.'};
            app.UsebinaryoverlaponlyCheckBox = uicheckbox(app.PlottingTab);
            app.UsebinaryoverlaponlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsebinaryoverlaponlyCheckBoxValueChanged, true);
            app.UsebinaryoverlaponlyCheckBox.Tooltip = {'If checked, only considers pixels in the overlap portion of the binary images for calculating the scatter/R distribution (locations where both pixels = True). Otherwise, uses all pixels inside the main ROI.'};
            app.UsebinaryoverlaponlyCheckBox.Text = 'Use binary overlap only';
            app.UserawipsicontraCheckBox = uicheckbox(app.PlottingTab);
            app.UserawipsicontraCheckBox.ValueChangedFcn = createCallbackFcn(app, @UserawipsicontraCheckBoxValueChanged, true);
            app.UserawipsicontraCheckBox.Tooltip = {'If checked, uses raw images for calculating scatter/R distribution. Otherwise, uses background subtracted images.'};
            app.UserawipsicontraCheckBox.Text = 'Use raw ipsi/contra';
            app.SaveRdistributionpixeldatatoexcelButton = uibutton(app.PlottingTab, 'push');
            app.SaveRdistributionpixeldatatoexcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRdistributionpixeldatatoexcelButtonPushed, true);
            app.SaveRdistributionpixeldatatoexcelButton.Tooltip = {'Saves the intensity for every pixel pair that went into the R distribution. Can be a large excel file (more than five MB).'; ''; 'Note this is the intensity data after change editing applied (pixels where either ipsi or contra are 0 had that 0 value changed to a 1). Pixels where both are 0 are left as 0.'};
            app.SaveRdistributionpixeldatatoexcelButton.Text = 'Save R distribution pixel data to excel';
            app.NumberofchangedpixelsEditFieldLabel = uilabel(app.PlottingTab);
            app.NumberofchangedpixelsEditFieldLabel.Text = 'Number of changed pixels';
            app.NumberofchangedpixelsEditField = uieditfield(app.PlottingTab, 'numeric');
            app.NumberofchangedpixelsEditField.Limits = [0 Inf];
            app.NumberofchangedpixelsEditField.RoundFractionalValues = 'on';
            app.NumberofchangedpixelsEditField.ValueDisplayFormat = '%i';
            app.NumberofchangedpixelsEditField.Editable = 'off';
            app.NumberofchangedpixelsEditField.Tooltip = {'Pixels where either ipsi or contra (just one in pair) had a value of 0 are changed to have a value of 1. This counter counts how many times this happened.'};
            app.SaveRdistribtionplotdatatoexcelButton = uibutton(app.PlottingTab, 'push');
            app.SaveRdistribtionplotdatatoexcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRdistribtionplotdatatoexcelButtonPushed, true);
            app.SaveRdistribtionplotdatatoexcelButton.Tooltip = {'Saves just the data that went into the plot (relative percent Y versus log units X). Number of points in excel data depends on the bin size set.'};
            app.SaveRdistribtionplotdatatoexcelButton.Text = 'Save R distribtion plot data to excel';
            app.Label = uilabel(app.AnalyzesinglematTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.AnalyzesinglematTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {'Tooltips will look like this.'};
            app.TextArea.Value = {'Many of the buttons/edit fields/checkboxes etc above have tooltips with extra information. Hover your mouse over them to view these tooltips.'};
            app.SavewhatisshowntoanimageButton_AnalyzeTab = uibutton(app.AnalyzesinglematTab, 'push');
            app.SavewhatisshowntoanimageButton_AnalyzeTab.ButtonPushedFcn = createCallbackFcn(app, @SavewhatisshowntoanimageButton_AnalyzeTabPushed, true);
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Enable = 'off';
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Tooltip = {'Saves whatever is shown in right panel as an image.'};
            app.SavewhatisshowntoanimageButton_AnalyzeTab.Text = 'Save what is shown to an image';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maskAndAnalyze_workDev

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end